classdef covid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryListBoxLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Countries
        States
        Dates
        data
        cumCases
        cumDeaths
        dailyCases
        dailyDeaths
        ShowCases
        ShowDeaths
        ShowBoth
        ShowCumulative
        ShowDaily
        currentStates
        dataIndex
        Averagedofdays
        AveragesCumCases
        AveragesCumDeaths
        AveragesDailyCases
        AveragesDailyDeaths
    end
    
    methods (Access = private)
        
        function calculateData(app)
            %Extract cumulative data
            app.cumCases=zeros(size(app.data));
            app.cumDeaths=zeros(size(app.data));
            for r=1:size(app.data,1)
                for c=1:size(app.data,2)
                    vec=app.data{r,c};
                    app.cumCases(r,c)=vec(1);
                    app.cumDeaths(r,c)=vec(2);
                end
            end
            
            %Calculate daily data
            app.dailyCases=zeros(size(app.data));
            app.dailyDeaths=zeros(size(app.data));
            for m=1:size(app.data,1)
                for n=1:size(app.data,2)
                    if n==1
                        app.dailyCases(m,n)=app.cumCases(m,n);
                        app.dailyDeaths(m,n)=app.cumDeaths(m,n);
                    else
                        app.dailyCases(m,n)=app.cumCases(m,n)-app.cumCases(m,n-1);
                        app.dailyDeaths(m,n)=app.cumDeaths(m,n)-app.cumDeaths(m,n-1);
                    end
                end
            end

            %Calculate Global data
            app.dailyCases=[sum(app.dailyCases);app.dailyCases];
            app.dailyDeaths=[sum(app.dailyDeaths);app.dailyDeaths];
            app.cumCases=[sum(app.cumCases);app.cumCases];
            app.cumDeaths=[sum(app.cumDeaths);app.cumDeaths];
            app.Countries=[{'Global'};app.Countries];
        end

        function updateStates(app)
            app.States=[{'All'};app.States];
            for j=1:size(app.data,1)
                if isempty(app.States{j})
                    app.States{j}='All';
                end
            end
        end

        function uniqueCountries(app)
            [~,ia1]=unique(app.Countries,'stable');
            app.cumCases=app.cumCases(ia1,:);
            app.cumDeaths=app.cumDeaths(ia1,:);
            app.dailyCases=app.dailyCases(ia1,:);
            app.dailyDeaths=app.dailyDeaths(ia1,:);
        end

        function loadCountryList(app)
            set(app.CountryListBox,'Items',unique(app.Countries,'stable'));
        end
        
        function loadStateList(app)
            value = app.CountryListBox.Value;
            stateIdx=[];
            for i=1:numel(app.Countries)
                if strcmp(app.Countries{i}, value)
                    stateIdx=[stateIdx;i];
                end
                app.currentStates=app.States(stateIdx);
            end
            set(app.StateorRegionListBox,'Items',app.currentStates);
        end

        function computeAverages(app)
            app.AveragesCumCases = movmean(app.cumCases,app.Averagedofdays,2);
            app.AveragesCumDeaths = movmean(app.cumDeaths,app.Averagedofdays,2);
            app.AveragesDailyCases = movmean(app.dailyCases,app.Averagedofdays,2);
            app.AveragesDailyDeaths = movmean(app.dailyDeaths,app.Averagedofdays,2);
        end

        function plotData(app)
            cla(app.UIAxes,'reset');

            %Create plot
            grid(app.UIAxes,'on');
            if app.ShowCases
                if app.ShowCumulative
                    if app.Averagedofdays==1
                        bar(app.UIAxes,app.Dates,app.cumCases(app.dataIndex,:))
                    else
                        bar(app.UIAxes,app.Dates,app.AveragesCumCases(app.dataIndex,:))
                    end
                else
                    if app.Averagedofdays==1
                        bar(app.UIAxes,app.Dates,app.dailyCases(app.dataIndex,:))
                    else
                        bar(app.UIAxes,app.Dates,app.AveragesDailyCases(app.dataIndex,:))
                    end
                end
                hold(app.UIAxes,'on');
            end
            if app.ShowDeaths
                if app.ShowCumulative
                    if app.Averagedofdays==1
                        plot(app.UIAxes,app.Dates,app.cumDeaths(app.dataIndex,:),'r-')
                    else
                        plot(app.UIAxes,app.Dates,app.AveragesCumDeaths(app.dataIndex,:),'r-')
                    end
                else
                    if app.Averagedofdays==1
                        plot(app.UIAxes,app.Dates,app.dailyDeaths(app.dataIndex,:),'r-')
                    else
                        plot(app.UIAxes,app.Dates,app.AveragesDailyDeaths(app.dataIndex,:),'r-')
                    end
                end
                hold(app.UIAxes,'on');
            end
            if app.ShowBoth
                if app.Averagedofdays==1
                    if app.ShowCumulative
                        yyaxis(app.UIAxes,'left')
                        bar(app.UIAxes,app.Dates,app.cumCases(app.dataIndex,:))
                        yyaxis(app.UIAxes,'right')
                        plot(app.UIAxes,app.Dates,app.cumDeaths(app.dataIndex,:),'r-')
                    else
                        yyaxis(app.UIAxes,'left')
                        bar(app.UIAxes,app.Dates,app.dailyCases(app.dataIndex,:))
                        yyaxis(app.UIAxes,'right')
                        plot(app.UIAxes,app.Dates,app.dailyDeaths(app.dataIndex,:),'r-')
                    end
                else
                    if app.ShowCumulative
                        yyaxis(app.UIAxes,'left')
                        bar(app.UIAxes,app.Dates,app.AveragesCumCases(app.dataIndex,:))
                        yyaxis(app.UIAxes,'right')
                        plot(app.UIAxes,app.Dates,app.AveragesCumDeaths(app.dataIndex,:),'r-')
                    else
                        yyaxis(app.UIAxes,'left')
                        bar(app.UIAxes,app.Dates,app.AveragesDailyCases(app.dataIndex,:))
                        yyaxis(app.UIAxes,'right')
                        plot(app.UIAxes,app.Dates,app.AveragesDailyDeaths(app.dataIndex,:),'r-')
                    end
                end
            end
            hold(app.UIAxes,'off');

            %Define plot title
            if app.ShowCumulative
                viewOption='Cumulative';
            elseif app.ShowDaily
                viewOption='Daily';
            end
    
            if app.ShowCases
                caseOption='Cases';
            elseif app.ShowDeaths
                caseOption='Deaths';
            elseif app.ShowBoth
                caseOption='Cases and Deaths';
            end
    
            regionOption=app.StateorRegionListBox.Value;
            if strcmp(regionOption,'All')
                if strcmp(app.CountryListBox.Value,'Global')
                    if app.Averagedofdays==1
                        T=strcat(viewOption,' number of',{' '},caseOption,' Globally');
                    else
                        T=strcat(viewOption,' number of',{' '},caseOption,' Globally',{' '},'(',num2str(app.Averagedofdays),'-day mean)');
                    end
                else
                    if app.Averagedofdays==1
                        T=strcat(viewOption,' number of',{' '},caseOption,' in',{' '},app.CountryListBox.Value);
                    else
                        T=strcat(viewOption,' number of',{' '},caseOption,' in',{' '},app.CountryListBox.Value,{' '},'(',num2str(app.Averagedofdays),'-day mean)');
                    end
                end
            else
                if app.Averagedofdays==1
                    T=strcat(viewOption,' number of',{' '},caseOption,' in',{' '},app.StateorRegionListBox.Value);
                else
                    T=strcat(viewOption,' number of',{' '},caseOption,' in',{' '},app.StateorRegionListBox.Value,{' '},'(',num2str(app.Averagedofdays),'-day mean)');
                end
            end
            app.UIAxes.Title.String=T;
        end

        function setDefaults(app)
            app.Averagedofdays = true;
            app.AveragedofdaysSlider.Value = app.AveragedofdaysSlider.Limits(1);
            
            app.CountryListBox.Value=app.CountryListBox.Items(1);
            app.StateorRegionListBox.Value=app.StateorRegionListBox.Items(1);
            
            app.ShowCumulative = true;
            app.ShowCases = true;
            
            app.CasesButton.Value = true;
            app.DeathsButton.Value = false;
            app.BothButton.Value = false;
            
            app.CumulativeButton.Value = true;
            app.DailyButton.Value = false;
            
            app.dataIndex=1;

            app.calculateData();
            app.updateStates();
            app.uniqueCountries();
            app.loadCountryList();
            app.loadStateList();
            app.computeAverages();
            app.plotData();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data
            app.Countries = covid_data(2:end,1);
            app.States = covid_data(2:end,2);
            app.Dates = datetime(covid_data(1,3:end),'Format','MM-dd-yy');
            app.data=covid_data(2:end,3:end);
            app.setDefaults();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            value = app.CountryListBox.Value;
            stateIdx=[];
            for i=1:numel(app.Countries)
                if strcmp(app.Countries{i}, value)
                    stateIdx=[stateIdx;i];
                end
                app.currentStates=app.States(stateIdx);
            end
            loadStateList(app);
            StateorRegionListBoxValueChanged(app);
            app.plotData();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.ShowCases = app.CasesButton.Value;
            app.ShowDeaths = app.DeathsButton.Value;
            app.ShowBoth = app.BothButton.Value;
            app.plotData();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            app.plotData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            value = app.StateorRegionListBox.Value;
            for i=1:numel(app.Countries)
                if strcmp(app.Countries{i}, app.CountryListBox.Value)
                    for j=1:numel(app.currentStates)
                        if strcmp(app.currentStates{j},value)
                            app.dataIndex=i+j-1;
                        end
                    end
                end
            end
            app.plotData();
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            value = app.AveragedofdaysSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value
            app.Averagedofdays = event.Source.MajorTicks(minIdx);
            app.computeAverages();
            app.plotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'COVID-19 Data Visualization (Data source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.UIFigure);
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end